classdef SoOpParamsEditor < matlab.apps.AppBase
    %SOOPPARAMSEDITOR sets parameters of Tx and Rx.
    %   Copyright (C) 2023 Seho Kim
    %       
    %   This file is part of SOCRATES.
    %       
    %   SOCRATES is free software: you can redistribute it and/or
    %   modify it under the terms of the GNU General Public License as
    %   published by the Free Software Foundation, either version 3 of the
    %   License, or (at your option) any later version.
    %        
    %   SOCRATES is distributed in the hope that it will be useful, but
    %   WITHOUT ANY WARRANTY; without even the implied warranty of
    %   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
    %   General Public License for more details.
    %   
    %   You should have received a copy of the GNU General Public
    %   License along with this program. If not, see
    %   <https://www.gnu.org/licenses/>.
    %   
    %   Version 1.0

    % Properties that correspond to app components
    properties (Access = public)
        SoOpParameterEditorUIFigure  matlab.ui.Figure
        OKButton                     matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        ReceiverInputsPanel          matlab.ui.container.Panel
        dBgainLabel                  matlab.ui.control.Label
        AntennaPatternPanel          matlab.ui.container.Panel
        TypeDropDown                 matlab.ui.control.DropDown
        TypeDropDownLabel            matlab.ui.control.Label
        GGPanel                      matlab.ui.container.Panel
        dBxpolLabel                  matlab.ui.control.Label
        dBxplLabel                   matlab.ui.control.Label
        degResLabel                  matlab.ui.control.Label
        degHPBWLabel                 matlab.ui.control.Label
        HPBWEditField                matlab.ui.control.NumericEditField
        HPBWEditFieldLabel           matlab.ui.control.Label
        SidelobelevelEditField       matlab.ui.control.NumericEditField
        SidelobelevelEditFieldLabel  matlab.ui.control.Label
        ResolutionEditField          matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel     matlab.ui.control.Label
        CrosspollevelEditField       matlab.ui.control.NumericEditField
        CrosspollevelEditFieldLabel  matlab.ui.control.Label
        UserdefinedPanel             matlab.ui.container.Panel
        CopolLabel                   matlab.ui.control.Label
        CopolFileSelector            fileSelector
        CrosspolLabel                matlab.ui.control.Label
        CrosspolFileSelector         fileSelector
        IdealLabel                   matlab.ui.control.Label
        GainEditField                matlab.ui.control.NumericEditField
        GainEditFieldLabel           matlab.ui.control.Label
        SpecularEditField            matlab.ui.control.NumericEditField
        SpecularEditFieldLabel       matlab.ui.control.Label
        PolarizationRxDropDown       matlab.ui.control.DropDown
        PolarizationRxDropDownLabel  matlab.ui.control.Label
        TransmitterInputsPanel       matlab.ui.container.Panel
        dBeirpLabel                  matlab.ui.control.Label
        kHzLabel                     matlab.ui.control.Label
        MHzLabel                     matlab.ui.control.Label
        BandwidthEditField           matlab.ui.control.NumericEditField
        BandwidthEditFieldLabel      matlab.ui.control.Label
        EIRPEditField                matlab.ui.control.NumericEditField
        EIRPEditFieldLabel           matlab.ui.control.Label
        PolarizationTxDropDown       matlab.ui.control.DropDown
        PolarizationTxDropDownLabel  matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel      matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        NameEditFieldLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Main app
        SoOpParamsObj % Input SoOp system parameters
    end
    
    methods (Access = private)
        
        function getAntPat(app)
            switch app.TypeDropDown.Value
                case 'Ideal'
                    app.UserdefinedPanel.Visible = "off";
                    app.GGPanel.Visible = "off";
                case 'Generalized-Gaussian'
                    app.UserdefinedPanel.Visible = "off";
                    app.GGPanel.Visible = "on";
                    app.HPBWEditField.Value = app.SoOpParamsObj.antPatGG.HPBW_deg;
                    app.SidelobelevelEditField.Value = app.SoOpParamsObj.antPatGG.SLL_dB;
                    app.CrosspollevelEditField.Value = app.SoOpParamsObj.antPatGG.XPL_dB;
                    app.ResolutionEditField.Value = app.SoOpParamsObj.antPatGG.res_deg;
                case 'User-defined'
                    app.UserdefinedPanel.Visible = "on";
                    app.GGPanel.Visible = "off";
                    if isfile(app.SoOpParamsObj.antPatFilepath.Co)
                        app.CopolFileSelector.Path = app.SoOpParamsObj.antPatFilepath.Co;
                    end
                    if isfile(app.SoOpParamsObj.antPatFilepath.X)
                        app.CrosspolFileSelector.Path = app.SoOpParamsObj.antPatFilepath.X;
                    end
                    app.CopolFileSelector.Filter = {'*.csv', 'Antenna pattern files (*.csv)'};
                    app.CrosspolFileSelector.Filter = {'*.csv', 'Antenna pattern files (*.csv)'};
            end
        end

        function setAntPat(app)
            app.SoOpParamsObj.antPatTypeRx = app.TypeDropDown.Value;
            switch app.TypeDropDown.Value
                case 'Ideal'
                    % Do nothing
                case 'Generalized-Gaussian'
                    app.SoOpParamsObj.antPatGG.HPBW_deg = app.HPBWEditField.Value;
                    app.SoOpParamsObj.antPatGG.SLL_dB = app.SidelobelevelEditField.Value;
                    app.SoOpParamsObj.antPatGG.XPL_dB = app.CrosspollevelEditField.Value;
                    app.SoOpParamsObj.antPatGG.res_deg = app.ResolutionEditField.Value;
                case 'User-defined'
                    app.SoOpParamsObj.antPatFilepath.Co = app.CopolFileSelector.Path;
                    app.SoOpParamsObj.antPatFilepath.X = app.CrosspolFileSelector.Path;
            end
        end

        function getTx(app)
            app.NameEditField.Value = app.SoOpParamsObj.TxName;
            app.FrequencyEditField.Value = app.SoOpParamsObj.f_MHz;
            app.PolarizationTxDropDown.Value = app.SoOpParamsObj.polTx;
            app.EIRPEditField.Value = app.SoOpParamsObj.EIRP_Tx_dB;
            app.BandwidthEditField.Value = app.SoOpParamsObj.BW_Tx_kHz;
        end

        function getRx(app)
            app.SpecularEditField.Value = app.SoOpParamsObj.nSpMax;
            app.GainEditField.Value = app.SoOpParamsObj.gainRx_dB;
            app.PolarizationRxDropDown.Value = app.SoOpParamsObj.polRx;
            app.TypeDropDown.Value = app.SoOpParamsObj.antPatTypeRx;
            getAntPat(app);
        end
        
        function setSoOpParamsObj(app)
            app.SoOpParamsObj.TxName = app.NameEditField.Value;
            app.SoOpParamsObj.f_MHz = app.FrequencyEditField.Value;
            app.SoOpParamsObj.polTx = app.PolarizationTxDropDown.Value;
            app.SoOpParamsObj.EIRP_Tx_dB = app.EIRPEditField.Value;
            app.SoOpParamsObj.BW_Tx_kHz = app.BandwidthEditField.Value;
            app.SoOpParamsObj.nSpMax = app.SpecularEditField.Value;
            app.SoOpParamsObj.gainRx_dB = app.GainEditField.Value;
            app.SoOpParamsObj.polRx = app.PolarizationRxDropDown.Value;
            app.SoOpParamsObj.antPatTypeRx = app.TypeDropDown.Value;
            setAntPat(app);
            updateSoOpParamsObj(app.CallingApp, app.SoOpParamsObj);
        end

        function init(app)
            getTx(app);
            getRx(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, SoOpParamsObj)
            app.CallingApp = caller;
            app.SoOpParamsObj = SoOpParamsObj;
            init(app);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            getAntPat(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            setSoOpParamsObj(app);
            delete(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            setSoOpParamsObj(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SoOpParameterEditorUIFigure = uifigure('Visible', 'off');
            app.SoOpParameterEditorUIFigure.Name = 'SoOp Parameter Editor';
            app.SoOpParameterEditorUIFigure.Icon = 'SOCRATES-logo-icon.png';
            app.TransmitterInputsPanel = uipanel(app.SoOpParameterEditorUIFigure);
            app.TransmitterInputsPanel.Title = 'Transmitter Inputs';
            app.NameEditFieldLabel = uilabel(app.TransmitterInputsPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.TransmitterInputsPanel, 'text');
            app.FrequencyEditFieldLabel = uilabel(app.TransmitterInputsPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.TransmitterInputsPanel, 'numeric');
            app.PolarizationTxDropDownLabel = uilabel(app.TransmitterInputsPanel);
            app.PolarizationTxDropDownLabel.Text = 'Polarization';
            app.PolarizationTxDropDown = uidropdown(app.TransmitterInputsPanel);
            app.PolarizationTxDropDown.Items = {'RHCP', 'LHCP', 'H-pol', 'V-pol'};
            app.PolarizationTxDropDown.Value = 'RHCP';
            app.EIRPEditFieldLabel = uilabel(app.TransmitterInputsPanel);
            app.EIRPEditFieldLabel.Text = 'EIRP';
            app.EIRPEditField = uieditfield(app.TransmitterInputsPanel, 'numeric');
            app.BandwidthEditFieldLabel = uilabel(app.TransmitterInputsPanel);
            app.BandwidthEditFieldLabel.Text = 'Bandwidth';
            app.BandwidthEditField = uieditfield(app.TransmitterInputsPanel, 'numeric');
            app.MHzLabel = uilabel(app.TransmitterInputsPanel);
            app.MHzLabel.Text = 'MHz';
            app.kHzLabel = uilabel(app.TransmitterInputsPanel);
            app.kHzLabel.Text = 'kHz';
            app.dBeirpLabel = uilabel(app.TransmitterInputsPanel);
            app.dBeirpLabel.Text = 'dB';
            app.ReceiverInputsPanel = uipanel(app.SoOpParameterEditorUIFigure);
            app.ReceiverInputsPanel.Title = 'Receiver Inputs';
            app.PolarizationRxDropDownLabel = uilabel(app.ReceiverInputsPanel);
            app.PolarizationRxDropDownLabel.Text = 'Polarization';
            app.PolarizationRxDropDown = uidropdown(app.ReceiverInputsPanel);
            app.PolarizationRxDropDown.Items = {'RHCP', 'LHCP', 'H-pol', 'V-pol'};
            app.PolarizationRxDropDown.Value = 'LHCP';
            app.SpecularEditFieldLabel = uilabel(app.ReceiverInputsPanel);
            app.SpecularEditFieldLabel.Text = '# Specular';
            app.SpecularEditField = uieditfield(app.ReceiverInputsPanel, 'numeric');
            app.GainEditFieldLabel = uilabel(app.ReceiverInputsPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.ReceiverInputsPanel, 'numeric');
            app.AntennaPatternPanel = uipanel(app.ReceiverInputsPanel);
            app.AntennaPatternPanel.Title = 'Antenna Pattern';
            app.IdealLabel = uilabel(app.AntennaPatternPanel);
            app.IdealLabel.Text = 'Assuming an ideal antenna';
            app.UserdefinedPanel = uipanel(app.AntennaPatternPanel);
            app.UserdefinedPanel.Visible = 'off';
            app.CrosspolFileSelector = fileSelector(app.UserdefinedPanel);
            app.CrosspolFileSelector.Title = "Select an Antenna Pattern File for Cross-polarization";
            app.CrosspolLabel = uilabel(app.UserdefinedPanel);
            app.CrosspolLabel.Text = 'Cross-pol';
            app.CopolFileSelector = fileSelector(app.UserdefinedPanel);
            app.CopolFileSelector.Title = "Select an Antenna Pattern File for Co-polarization";
            app.CopolLabel = uilabel(app.UserdefinedPanel);
            app.CopolLabel.Text = 'Co-pol';
            app.GGPanel = uipanel(app.AntennaPatternPanel);
            app.GGPanel.Visible = 'off';
            app.CrosspollevelEditFieldLabel = uilabel(app.GGPanel);
            app.CrosspollevelEditFieldLabel.Text = 'Cross-pol level';
            app.CrosspollevelEditField = uieditfield(app.GGPanel, 'numeric');
            app.ResolutionEditFieldLabel = uilabel(app.GGPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.GGPanel, 'numeric');
            app.SidelobelevelEditFieldLabel = uilabel(app.GGPanel);
            app.SidelobelevelEditFieldLabel.Text = 'Sidelobe level';
            app.SidelobelevelEditField = uieditfield(app.GGPanel, 'numeric');
            app.HPBWEditFieldLabel = uilabel(app.GGPanel);
            app.HPBWEditFieldLabel.Text = 'HPBW';
            app.HPBWEditField = uieditfield(app.GGPanel, 'numeric');
            app.degHPBWLabel = uilabel(app.GGPanel);
            app.degHPBWLabel.Text = 'deg';
            app.degResLabel = uilabel(app.GGPanel);
            app.degResLabel.Text = 'deg';
            app.dBxplLabel = uilabel(app.GGPanel);
            app.dBxplLabel.Text = 'dB';
            app.dBxpolLabel = uilabel(app.GGPanel);
            app.dBxpolLabel.Text = 'dB';
            app.TypeDropDownLabel = uilabel(app.AntennaPatternPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.AntennaPatternPanel);
            app.TypeDropDown.Items = {'Ideal', 'Generalized-Gaussian', 'User-defined'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Ideal';
            app.dBgainLabel = uilabel(app.ReceiverInputsPanel);
            app.dBgainLabel.Text = 'dB';
            app.CancelButton = uibutton(app.SoOpParameterEditorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.SoOpParameterEditorUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.OKButton = uibutton(app.SoOpParameterEditorUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.SoOpParameterEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoOpParamsEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SoOpParameterEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SoOpParameterEditorUIFigure)
        end
    end
end