classdef pathSelector < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        EditField   matlab.ui.control.EditField
        Button      matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        PathChanged
    end

    properties (Access = public)
        Path string {mustBeFolder(Path)} % Full path to the folder
        Title string {mustBeText(Title)} % Dialog box title, specified as a character vector or string scalar.
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(comp, event)
            comp.Path = comp.EditField.Value;

            notify(comp,"PathChanged")
        end

        % Button pushed function: Button
        function ButtonPushed(comp, event)
            pathName = uigetdir(comp.EditField.Value, comp.Title);
            if ~isequal(pathName,0)
                comp.Path = pathName;
            end
            
            notify(comp,"PathChanged")
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            comp.EditField.Value = comp.Path;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 338 40];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.Button = uibutton(comp.GridLayout, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
            comp.Button.Icon = 'folderIcon.png';
            comp.Button.Text = '';
            comp.EditField = uieditfield(comp.GridLayout, 'text');
            comp.EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditFieldValueChanged, true);
        end
    end
end